classdef processC3D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PrepareC3DfileUIFigure          matlab.ui.Figure
        Selectc3dfileButton             matlab.ui.control.Button
        UIAxesSteps                     matlab.ui.control.UIAxes
        PrepareC3DfileforOpenSimsimulationsLabel  matlab.ui.control.Label
        SelectaC3DfileLabel             matlab.ui.control.Label
        detectforceplatesautomaticallyCheckBox  matlab.ui.control.CheckBox
        filterGRFsCheckBox              matlab.ui.control.CheckBox
        setGRFstozerooutsideregionoffootcontactCheckBox  matlab.ui.control.CheckBox
        detectwalkingdirectionautomaticallyCheckBox  matlab.ui.control.CheckBox
        RotatearoundyaxisDropDownLabel  matlab.ui.control.Label
        RotatearoundyaxisDropDown       matlab.ui.control.DropDown
        StartprocessingButton           matlab.ui.control.Button
        leftListBoxLabel                matlab.ui.control.Label
        leftListBox                     matlab.ui.control.ListBox
        correctfootstrikeeventsLabel    matlab.ui.control.Label
        rightListBoxLabel               matlab.ui.control.Label
        rightListBox                    matlab.ui.control.ListBox
        openselectedfilewithdefaultprogramButton  matlab.ui.control.Button
        LogTextAreaLabel                matlab.ui.control.Label
        LogTextArea                     matlab.ui.control.TextArea
        UIAxesGRFs                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        c3dFileName % Description
        path % Description
    end
    
    methods (Access = private)
        
        function writeLog(app, msg)
            app.LogTextArea.Value = [app.LogTextArea.Value; msg];
            drawnow; % Force text area to update
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % add OpenSim jar file to java path
            addingSuccessfull = 0;
            systemPaths = strsplit(getenv('PATH'), ';');
            
            for i = 1 : numel(systemPaths)
                if contains(systemPaths{i}, 'OpenSim')
                    systemPaths{i} = strrep(systemPaths{i}, '"', '');
                    pathToAdd = systemPaths{i};
                    javaaddpath(pathToAdd);
                    addingSuccessfull = 1;
                    break;
                end
            end
            
            if ~addingSuccessfull
                errordlg('Check if you have installed OpenSim properly and added it to System PATH variable. Typically the installation path is C:\OpenSim 4.3\bin');
                app.delete;
            end
            
            hold(app.UIAxesSteps, 'on');
            hold(app.UIAxesGRFs, 'on');
        end

        % Button pushed function: Selectc3dfileButton
        function Selectc3dfileButtonPushed(app, event)
            [app.c3dFileName, app.path] = uigetfile('*.c3d', 'MultiSelect', 'off', 'Select C3D file of trial');
            app.SelectaC3DfileLabel.Text = fullfile(app.path, app.c3dFileName);
            
            app.openselectedfilewithdefaultprogramButton.Visible = 1;
            
            % get events and put them in the list for selection
            acq = btkReadAcquisition(fullfile(app.path, app.c3dFileName));
            c3devents = btkGetEvents(acq);
            frequency = btkGetPointFrequency(acq);
            firstFrame = btkGetFirstFrame(acq);
            
            if isfield(c3devents, 'Left_Foot_Strike')
                leftFootStrikeList = sprintfc('%.0f', c3devents.Left_Foot_Strike * frequency);
                app.leftListBox.Items = leftFootStrikeList;
                app.leftListBox.Value = leftFootStrikeList(1 : end - 1);
                for i = 1 : size(leftFootStrikeList, 2) - 1
                    fill(app.UIAxesSteps, [str2num(leftFootStrikeList{i}) str2num(leftFootStrikeList{i}) str2num(leftFootStrikeList{i + 1})], [0 1 0], 'r', 'FaceAlpha', 0.3, 'EdgeColor', "none");
                end
            end
            if isfield(c3devents, 'Right_Foot_Strike')
                rightFootStrikeList = sprintfc('%.0f', c3devents.Right_Foot_Strike * frequency);
                app.rightListBox.Items = rightFootStrikeList;
                app.rightListBox.Value = rightFootStrikeList(1 : end - 1);
                for i = 1 : size(rightFootStrikeList, 2) - 1
                    fill(app.UIAxesSteps, [str2num(rightFootStrikeList{i}) str2num(rightFootStrikeList{i}) str2num(rightFootStrikeList{i + 1})], [-1 0 0], 'g', 'FaceAlpha', 0.3, 'EdgeColor', "none");
                end
            end
            
            xlim(app.UIAxesSteps, [min(str2num(rightFootStrikeList{1}), str2num(leftFootStrikeList{1})) - 10    max(str2num(rightFootStrikeList{end}), str2num(leftFootStrikeList{end})) + 10]);
            ylim(app.UIAxesSteps, [-1.1 1.1]);
            
            writeLog(app, [app.c3dFileName ' sucessfully read']);
        end

        % Value changed function: 
        % detectwalkingdirectionautomaticallyCheckBox
        function detectwalkingdirectionautomaticallyCheckBoxValueChanged(app, event)
            value = app.detectwalkingdirectionautomaticallyCheckBox.Value;
            app.RotatearoundyaxisDropDown.Visible = mod(value + 1, 2);
            app.RotatearoundyaxisDropDownLabel.Visible = mod(value + 1, 2);
        end

        % Button pushed function: StartprocessingButton
        function StartprocessingButtonPushed(app, event)
            [folder, baseFileNameNoExt, extension] = fileparts(fullfile(app.path, app.c3dFileName));
            output_folder = fullfile(folder, baseFileNameNoExt);
                        
            % create folder with the same name as c3d file
            if ~exist(output_folder, 'dir')
                mkdir(output_folder)
            end
            c3dpath = fullfile(app.path, app.c3dFileName);
            c3d = osimC3D(c3dpath, 1);
            c3d.rotateData('x', -90);
            
            c3d.convertMillimeters2Meters();
            
            nTrajectories = c3d.getNumTrajectories();
            
            if (nTrajectories ~= 0)
                c3d.writeTRC(fullfile(output_folder, 'marker_experimental.trc'));
            end
            
            nForces = c3d.getNumForces();
            
            if (nForces ~= 0)
                grfFileName = fullfile(output_folder, 'grf.mot');
                c3d.writeMOT(grfFileName);
                    
                %replace nan and -nan(ind)
                fid  = fopen(grfFileName,'r');
                f=fread(fid,'*char')';
                fclose(fid);
                f = strrep(f,'-nan(ind)','0');
                f = strrep(f,'nan','0');
                fid  = fopen(grfFileName,'w');
                fprintf(fid,'%s',f);
                fclose(fid);
                
            
                % open it again and apply filter
                forces = load_sto_file(grfFileName);
                forcesFields = fieldnames(forces);
            
                Fs = c3d.getRate_force();  % Sampling Frequency
                N  = 2;  % Filter Order
                Fc = 6;  % Cutoff Frequency
                [filter_b, filter_a] = butter(N,Fc/(Fs/2), 'low');
                %%
                forces = convertStruct(forces);
                for i = 1 : numel(forcesFields)
                   if char(forcesFields(i)) ~= "time"
                       forcesStruct_filtered.(char(forcesFields(i))) = filtfilt(filter_b, filter_a, forces.(char(forcesFields(i))));
                   else
                       % do not filter time
                       forcesStruct_filtered.(char(forcesFields(i))) = forces.(char(forcesFields(i)));
                   end
                end
            
                % write out the filtered data to the mot file again
                delete(grfFileName);
                write_sto_file(forcesStruct_filtered, grfFileName); 
                
            end
            
            verticalForces = forcesFields(contains(forcesFields, '_vy'));
            
            hold(app.UIAxesSteps,'on')
%             hold(app.UIAxes_2,'on')
            for i = 1 : numel(verticalForces)
                plot(app.UIAxesSteps, forcesStruct_filtered.(verticalForces{i}));
%                 plot(app.UIAxes_2, forces.(verticalForces{i}));
            end
            
        end

        % Button pushed function: 
        % openselectedfilewithdefaultprogramButton
        function openselectedfilewithdefaultprogramButtonPushed(app, event)
            winopen(fullfile(app.path, app.c3dFileName));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PrepareC3DfileUIFigure = uifigure('Visible', 'off');
            app.PrepareC3DfileUIFigure.Name = 'Prepare C3D file';
            app.Selectc3dfileButton = uibutton(app.PrepareC3DfileUIFigure, 'push');
            app.Selectc3dfileButton.ButtonPushedFcn = createCallbackFcn(app, @Selectc3dfileButtonPushed, true);
            app.Selectc3dfileButton.Text = 'Select c3d file';
            app.UIAxesSteps = uiaxes(app.PrepareC3DfileUIFigure);
            title(app.UIAxesSteps, 'Steps')
            xlabel(app.UIAxesSteps, '')
            ylabel(app.UIAxesSteps, '')
            app.PrepareC3DfileforOpenSimsimulationsLabel = uilabel(app.PrepareC3DfileUIFigure);
            app.PrepareC3DfileforOpenSimsimulationsLabel.Text = {'Prepare C3D file for OpenSim simulations'; 'creating marker_experimental.trc / grf.mot / grf.xml / settings.mat and a copy of original c3d-file'};
            app.SelectaC3DfileLabel = uilabel(app.PrepareC3DfileUIFigure);
            app.SelectaC3DfileLabel.Text = 'Select a C3D file';
            app.detectforceplatesautomaticallyCheckBox = uicheckbox(app.PrepareC3DfileUIFigure);
            app.detectforceplatesautomaticallyCheckBox.Text = 'detect forceplates automatically';
            app.detectforceplatesautomaticallyCheckBox.Value = true;
            app.filterGRFsCheckBox = uicheckbox(app.PrepareC3DfileUIFigure);
            app.filterGRFsCheckBox.Text = 'filter GRFs';
            app.filterGRFsCheckBox.Value = true;
            app.setGRFstozerooutsideregionoffootcontactCheckBox = uicheckbox(app.PrepareC3DfileUIFigure);
            app.setGRFstozerooutsideregionoffootcontactCheckBox.Text = 'set GRFs to zero outside region of foot contact';
            app.setGRFstozerooutsideregionoffootcontactCheckBox.Value = true;
            app.detectwalkingdirectionautomaticallyCheckBox = uicheckbox(app.PrepareC3DfileUIFigure);
            app.detectwalkingdirectionautomaticallyCheckBox.ValueChangedFcn = createCallbackFcn(app, @detectwalkingdirectionautomaticallyCheckBoxValueChanged, true);
            app.detectwalkingdirectionautomaticallyCheckBox.Text = 'detect walking direction automatically';
            app.detectwalkingdirectionautomaticallyCheckBox.Value = true;
            app.RotatearoundyaxisDropDownLabel = uilabel(app.PrepareC3DfileUIFigure);
            app.RotatearoundyaxisDropDownLabel.Visible = 'off';
            app.RotatearoundyaxisDropDownLabel.Text = 'Rotate around y axis';
            app.RotatearoundyaxisDropDown = uidropdown(app.PrepareC3DfileUIFigure);
            app.RotatearoundyaxisDropDown.Items = {'0°', '90°', '180°', '270°'};
            app.RotatearoundyaxisDropDown.Visible = 'off';
            app.RotatearoundyaxisDropDown.Value = '0°';
            app.StartprocessingButton = uibutton(app.PrepareC3DfileUIFigure, 'push');
            app.StartprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @StartprocessingButtonPushed, true);
            app.StartprocessingButton.Text = 'Start processing';
            app.leftListBoxLabel = uilabel(app.PrepareC3DfileUIFigure);
            app.leftListBoxLabel.Text = 'left ';
            app.leftListBox = uilistbox(app.PrepareC3DfileUIFigure);
            app.leftListBox.Items = {};
            app.leftListBox.Multiselect = 'on';
            app.leftListBox.Value = {};
            app.correctfootstrikeeventsLabel = uilabel(app.PrepareC3DfileUIFigure);
            app.correctfootstrikeeventsLabel.Text = 'correct foot strike events';
            app.rightListBoxLabel = uilabel(app.PrepareC3DfileUIFigure);
            app.rightListBoxLabel.Text = 'right';
            app.rightListBox = uilistbox(app.PrepareC3DfileUIFigure);
            app.rightListBox.Items = {};
            app.rightListBox.Multiselect = 'on';
            app.rightListBox.Value = {};
            app.openselectedfilewithdefaultprogramButton = uibutton(app.PrepareC3DfileUIFigure, 'push');
            app.openselectedfilewithdefaultprogramButton.ButtonPushedFcn = createCallbackFcn(app, @openselectedfilewithdefaultprogramButtonPushed, true);
            app.openselectedfilewithdefaultprogramButton.Visible = 'off';
            app.openselectedfilewithdefaultprogramButton.Text = 'open selected file with default program';
            app.LogTextAreaLabel = uilabel(app.PrepareC3DfileUIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.PrepareC3DfileUIFigure);
            app.LogTextArea.Editable = 'off';
            app.UIAxesGRFs = uiaxes(app.PrepareC3DfileUIFigure);
            title(app.UIAxesGRFs, 'GRFs')
            xlabel(app.UIAxesGRFs, 'frame')
            ylabel(app.UIAxesGRFs, '')
            % Show the figure after all components are created
            app.PrepareC3DfileUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = processC3D

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PrepareC3DfileUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PrepareC3DfileUIFigure)
        end
    end
end